classdef RoastGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        MainmenuTab                     matlab.ui.container.Tab
        LoadsettingsButton              matlab.ui.control.Button
        SavesettingsButton              matlab.ui.control.Button
        OptionalSelectT2MRIfileLabel    matlab.ui.control.Label
        SelectT1MRIfileLabel            matlab.ui.control.Label
        T2FileLoc                       matlab.ui.control.EditField
        BrowseButton_2                  matlab.ui.control.Button
        T1FileLoc                       matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ElectrodeTabGroup               matlab.ui.container.TabGroup
        ElecTab                         matlab.ui.container.Tab
        ElectrodeconfigurationLabel     matlab.ui.control.Label
        NumberofelectrodesEditField     matlab.ui.control.NumericEditField
        NumberofelectrodesEditFieldLabel  matlab.ui.control.Label
        CaptypeDropDown                 matlab.ui.control.DropDown
        CaptypeDropDownLabel            matlab.ui.control.Label
        TargetedtESTab                  matlab.ui.container.Tab
        ElectricfieldorientationDropDown  matlab.ui.control.DropDown
        ElectricfieldorientationDropDownLabel  matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        PriorityDropDown                matlab.ui.control.DropDown
        PriorityDropDownLabel           matlab.ui.control.Label
        CoordinatetypeDropDown          matlab.ui.control.DropDown
        CoordinatetypeDropDownLabel     matlab.ui.control.Label
        CapType1010TypeDiscRadius6mmHeight2mmLabel  matlab.ui.control.Label
        LoadsettingsButton_2            matlab.ui.control.Button
        SavesettingsButton_2            matlab.ui.control.Button
        OptionalSelectT2MRIfileLabel_2  matlab.ui.control.Label
        SelectT1MRIfileLabel_2          matlab.ui.control.Label
        T2FileLoc_2                     matlab.ui.control.EditField
        BrowseButton_4                  matlab.ui.control.Button
        T1FileLoc_2                     matlab.ui.control.EditField
        BrowseButton_3                  matlab.ui.control.Button
        StopButton_2                    matlab.ui.control.Button
        StartButton_2                   matlab.ui.control.Button
        AdvancedTab                     matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        ConductivityvaluesTab           matlab.ui.container.Tab
        SmLabel_8                       matlab.ui.control.Label
        SmLabel_7                       matlab.ui.control.Label
        SmLabel_6                       matlab.ui.control.Label
        SmLabel_5                       matlab.ui.control.Label
        SmLabel_4                       matlab.ui.control.Label
        SmLabel_3                       matlab.ui.control.Label
        SmLabel_2                       matlab.ui.control.Label
        SmLabel                         matlab.ui.control.Label
        ElectrodeEditField              matlab.ui.control.NumericEditField
        ElectrodeEditFieldLabel         matlab.ui.control.Label
        GelEditField                    matlab.ui.control.NumericEditField
        GelEditFieldLabel               matlab.ui.control.Label
        AirEditField                    matlab.ui.control.NumericEditField
        AirEditFieldLabel               matlab.ui.control.Label
        SkinEditField                   matlab.ui.control.NumericEditField
        SkinEditFieldLabel              matlab.ui.control.Label
        BoneEditField                   matlab.ui.control.NumericEditField
        BoneEditFieldLabel              matlab.ui.control.Label
        CerebrospinalfluidEditField     matlab.ui.control.NumericEditField
        CerebrospinalfluidLabel         matlab.ui.control.Label
        GraymatterEditField             matlab.ui.control.NumericEditField
        GraymatterEditFieldLabel        matlab.ui.control.Label
        WhitematterEditField            matlab.ui.control.NumericEditField
        WhitematterEditFieldLabel       matlab.ui.control.Label
        MeshoptionsTab                  matlab.ui.container.Tab
        MaximaltetrahedralelementvolumeEditField  matlab.ui.control.NumericEditField
        MaximaltetrahedralelementvolumeEditFieldLabel  matlab.ui.control.Label
        MaximalradiusedgeratioEditField  matlab.ui.control.NumericEditField
        MaximalradiusedgeratioEditFieldLabel  matlab.ui.control.Label
        MaximumdistancebetweenEditField  matlab.ui.control.NumericEditField
        MaximumdistancebetweenEditFieldLabel  matlab.ui.control.Label
        MinimalangleofasurfacetriangleEditField  matlab.ui.control.NumericEditField
        MinimalangleofasurfacetriangleEditFieldLabel  matlab.ui.control.Label
        MaximalsurfaceelementsizeEditField  matlab.ui.control.NumericEditField
        MaximalsurfaceelementsizeEditFieldLabel  matlab.ui.control.Label
        ZeropaddingEditField            matlab.ui.control.NumericEditField
        ZeropaddingEditFieldLabel       matlab.ui.control.Label
        ResamplingCheckBox              matlab.ui.control.CheckBox
        SimulationtagEditField          matlab.ui.control.EditField
        SimulationtagEditFieldLabel     matlab.ui.control.Label
        InfoTab                         matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        HelpfullinksLabel_2             matlab.ui.control.Label
        Hyperlink3_2                    matlab.ui.control.Hyperlink
        Hyperlink3                      matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        RightPanel                      matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        ElectrodepositionTab            matlab.ui.container.Tab
        ClicktoselectLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        DynamicElecTab % Electrode settings tab
        ElecPosLabel % Electrode position
        ElecPosValue
        ElecMag
        ElecMagValue
        ElecTypeLabel
        ElecSizeLabel
        ElecSizeValue

        RadiusValue
        RadiusLabel
        HeightValue
        HeightLabel
        ElecTypeDropDown

        ElecPosSelect

        InnerRadiusLabel
        InnerRadiusValue
        OuterRadiusLabel
        OuterRadiusValue

        Image
        SaveimageButton
        ElecImg
        DesiredElecImgPos
        ElecImgTxt


        DynamicElecType % Electrode type
        OrientationDropDown
        DynamicOrientationDropDown
        DynamicOrientationText

        PreviousElecType

        LengthLabel
        LengthValue

        WidthLabel
        WidthValue
        OrientationText

        imgLayer
        ringInnerAlpha

        LEFT_COLLUMN_WIDTH = 378;
        IMG_RATIO = 683 / 638;
        
        
    end
    properties (Access = public)
        MRIViewerTab
        MRIViewer
    end

    methods (Access = public)
        function createFigTab(app, TabNum, ~)
                app.MRIViewerTab = uitab(app.TabGroup3);
                app.MRIViewerTab.Title = 'MRI viewer';
                %app.MRIViewer = uiaxes(app.MRIViewerTab);
                %app.MRIViewer.Position = [1 2 604 474];
        end
    end

    methods (Access = private)
        function createElecTab(app, TabNum)
            % Dynamicaly create new electrode settings tabs as the user
            % requests them
            TabNum = TabNum -1;
            % Create tab
            app.DynamicElecTab{TabNum} = uitab(app.ElectrodeTabGroup);
            app.DynamicElecTab{TabNum}.Title = string(TabNum+1);
        end


        function resizeAppComponents(app, event)

            disp(event.Source)
            UIFigurePos = app.UIFigure.Position; % Size of UI

            % Right tab size (tab with electrode position and figures)
            app.TabGroup3.Position(3) = UIFigurePos(3) - 380;
            app.TabGroup3.Position(4) = UIFigurePos(4) - 14;

            % Left tab resize (tab with electrode position and figures)
            app.TabGroup2.Position(4) = UIFigurePos(4) - 11;

            % Resize electrode image
            app.Image.Position(4) = app.TabGroup3.Position(4) - 40;
            app.Image.Position(3) = app.TabGroup3.Position(3);

            % Reposition electrode image
            app.Image.Position(1) = app.TabGroup3.Position(3)/2 - app.Image.Position(3)/2;
            app.Image.Position(2) = 10;

        end
        function fillElecTab(app, TabNum)
            % Create electrode position text
            if TabNum == 1
                currentTabObj = app.ElecTab;
            else
                currentTabObj = app.DynamicElecTab{TabNum-1};
            end
            
            % Create stimulation magnitude text
            app.ElecMag{TabNum} = uilabel(currentTabObj);
            app.ElecMag{TabNum}.Position =  [6 115 142 22];
            app.ElecMag{TabNum}.Text = 'Stimulation intensity (mA)';

            % Create stimulation magnitude editable field
            app.ElecMagValue{TabNum} = uieditfield(currentTabObj, 'numeric');
            app.ElecMagValue{TabNum}.HorizontalAlignment = 'right';
            app.ElecMagValue{TabNum}.Position = [150 115 37 22];
            
            % Create electrode position label
            app.ElecPosLabel{TabNum} = uilabel(currentTabObj);
            app.ElecPosLabel{TabNum}.Position = [210 115 43 22];
            app.ElecPosLabel{TabNum}.Text = 'Position';

            % Create PositionEditField
            app.ElecPosValue{TabNum} = uieditfield(currentTabObj, 'text');
            app.ElecPosValue{TabNum}.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldValueChanged, true);
            app.ElecPosValue{TabNum}.Position = [290 115 34 22];
            app.ElecPosValue{TabNum}.HorizontalAlignment = 'right';           
            
            % Create HeightmmEditFieldLabel
            app.HeightLabel{TabNum} = uilabel(currentTabObj);
            app.HeightLabel{TabNum}.Position = [210 80 71 22];
            app.HeightLabel{TabNum}.Text  = 'Height (mm)';

            % Create HeightmmEditField
            app.HeightValue{TabNum} = uieditfield(currentTabObj, 'numeric');
            app.HeightValue{TabNum}.Position = [290 80 34 22];
            app.HeightValue{TabNum}.Value = 2; % Default
            app.HeightValue{TabNum}.ValueChangedFcn = createCallbackFcn(app, @elecHeightCallback, true);

            % Create TypeDropDownLabel
            app.ElecTypeLabel{TabNum} = uilabel(currentTabObj);
            app.ElecTypeLabel{TabNum}.Position = [6 80 30 22];
            app.ElecTypeLabel{TabNum}.Text = 'Type';

            % Create TypeDropDown
            app.ElecTypeDropDown{TabNum} = uidropdown(currentTabObj);
            app.ElecTypeDropDown{TabNum}.Items = {'Disc (default)', 'Pad', 'Ring'};
            app.ElecTypeDropDown{TabNum}.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.ElecTypeDropDown{TabNum}.Position = [84 80 103 22];
            app.ElecTypeDropDown{TabNum}.Value = 'Disc (default)';

            app.PreviousElecType{TabNum} = 'Disc (default)';

            % Create RadiusLabel{TabNum}
            app.RadiusLabel{TabNum} = uilabel(currentTabObj);
            app.RadiusLabel{TabNum}.Position = [6 45 70 22];
            app.RadiusLabel{TabNum}.Text = 'Radius (mm)';

            % Create RadiusValue{TabNum}
            app.RadiusValue{TabNum} = uieditfield(currentTabObj, 'numeric');
            app.RadiusValue{TabNum}.Position = [150 45 37 22];
            app.RadiusValue{TabNum}.Value = 6; % Default

        end

        function deleteElecTab(app, TabNum)
            % Delete electrode configuration tab requested
            delete(app.DynamicElecTab{TabNum});
            app.DynamicElecTab(TabNum) = [];

            TabNum = TabNum +1;
            delete(app.ElecPosLabel{TabNum});
            app.ElecPosLabel(TabNum) = [];

            delete(app.ElecPosValue{TabNum});
            app.ElecPosValue(TabNum) = [];

            delete(app.ElecMag{TabNum});
            app.ElecMag(TabNum) = [];

            delete(app.ElecMagValue{TabNum});
            app.ElecMagValue(TabNum) = [];

            delete(app.ElecTypeLabel{TabNum});
            app.ElecTypeLabel(TabNum) = [];

            delete(app.ElecTypeDropDown{TabNum});
            app.ElecTypeDropDown(TabNum) = [];

            delete(app.RadiusValue{TabNum});
            app.RadiusValue(TabNum) = [];

            delete(app.RadiusLabel{TabNum});
            app.RadiusLabel(TabNum) = [];

            delete(app.HeightValue{TabNum});
            app.HeightValue(TabNum) = [];

            delete(app.HeightLabel{TabNum});
            app.HeightLabel(TabNum) = [];

            if size(app.imgLayer, 1) >= TabNum
                app.imgLayer{TabNum, 1} = [];
                app.imgLayer{TabNum, 2} = [];
            end
            if size(app.ringInnerAlpha, 1) >= TabNum
                app.ringInnerAlpha{TabNum, 1} = [];
                app.ringInnerAlpha{TabNum, 2} = [];
            end
        end

        function drawElectrodePosition(app, pos, ElecType, ElecSize)
            % Draw electrode requested on the image shown in the electrode
            % position tab
            currentTab = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
            % Load the EEG layout image from file
            imageFile = '\Images\1020EEG.png';
            absPath = fileparts(mfilename('fullpath')); % absolute path to the folder containing the mlapp file

            [img, ~, alpha] = imread(strcat(absPath, imageFile)); % Load image
            originalImageSize = size(img);
            % Convert the alpha channel to type uint8
            alpha = uint8(alpha);
            alpha = alpha * 0.6;

            % Draw a circle on the image
            x = pos(1); % x-coordinate of the center of the shape
            y = pos(2); % y-coordinate of the center of the shape

            % Remove hole in alpha map for center of ring electrode if it
            % exists
            if size(app.ringInnerAlpha,1) >= currentTab
                app.ringInnerAlpha{currentTab, 1} = [];
                app.ringInnerAlpha{currentTab, 2} = [];
            end

            % Set inner ring alpha map = zero if it does not already
            % exist
            if size(app.ringInnerAlpha, 1) < currentTab
                app.ringInnerAlpha{currentTab, 1} = [];
                app.ringInnerAlpha{currentTab, 2} = [];
            end

            [h, w, ~] = size(img);
            [X, Y] = meshgrid(1:w, 1:h);
            if strcmp(ElecType, 'Disc (default)')
                % Create circle
                r = ElecSize * 2.5; % Roughly mm*2 = size of a 30cm head
                ElecShape = (X - x).^2 + (Y - y).^2 <= r^2;
                ElecShape = uint8(ElecShape);
                app.imgLayer{currentTab, 1} =  uint8(zeros(size(alpha))) + ElecShape*255;
                numPos = r;

            elseif strcmp(ElecType, 'Pad')
                ElecWidth = ElecSize(1) * 2.5;
                ElecLength = ElecSize(2) * 2.5;
                ElecShape = zeros(size(img, 1), size(img, 2));
                ElecShape(y-ElecLength/2:y+ElecLength/2,x-ElecWidth/2:x+ElecWidth/2) = 1;
                ElecShape = uint8(ElecShape);
                app.imgLayer{currentTab, 1} =  uint8(zeros(size(alpha))) + ElecShape*255;
                numPos = ElecWidth;
            elseif strcmp(ElecType, 'Ring')
                ElecInnerRad = ElecSize(1) * 2.5;
                ElecOuterRad = ElecSize(2) * 2.5;
                ElecShape = (X - x).^2 + (Y - y).^2 <= ElecOuterRad^2;
                ElecShape = uint8(ElecShape);
                % Set alpha map for entire ring = 255 on this layer
                app.imgLayer{currentTab, 1} =  uint8(zeros(size(alpha))) + ElecShape*255;
                InnerElecShape = (X - x).^2 + (Y - y).^2 <= ElecInnerRad^2;
                InnerElecShape = uint8(InnerElecShape);
                [xLoc, yLoc] = find(InnerElecShape); % Find x,y coords of inner ring alpha map
                % Set inner ring alpha map to zero for this layer
                app.ringInnerAlpha{currentTab, 1} = [app.ringInnerAlpha{currentTab, 1}; xLoc];
                app.ringInnerAlpha{currentTab, 2} = [app.ringInnerAlpha{currentTab, 2}; yLoc];
                app.imgLayer{currentTab, 1} =  app.imgLayer{currentTab, 1} - InnerElecShape*255;
                numPos = ElecOuterRad;
            end

            % Create a light blue color in RGB format
            lightBlue = [125 125 255];

            % Add the blue electrode to the electrode image layer
            zeroedImg = uint8(zeros(size(img)));
            tmpLayer = zeroedImg;
            tmpLayer(:, :, 1) = zeroedImg(:, :, 1) + lightBlue(1) *  ElecShape;
            tmpLayer(:, :, 2) = zeroedImg(:, :, 2) + lightBlue(2) *  ElecShape;
            tmpLayer(:, :, 3) = zeroedImg(:, :, 3) + lightBlue(3) *  ElecShape;
            app.imgLayer{currentTab, 2} = tmpLayer;


            % Save the current electrode image as a temporary image
            AvailableImages = dir(strcat(absPath, '\Images\'));
            for i = 1:size(AvailableImages, 1)
                currentImg = AvailableImages(i).name;
                if length(currentImg) > 9
                    if strcmp(currentImg(1:6), 'tmpimg')
                        if strcmp(currentImg(8), '.')
                            imgNum = currentImg(7);
                        elseif strcmp(currentImg(9), '.')
                            imgNum = currentImg(7:8);
                        elseif strcmp(currentImg(10), '.')
                            imgNum = currentImg(7:9);
                        elseif strcmp(currentImg(11), '.')
                            imgNum = currentImg(7:10);
                        end
                        delete(strcat(absPath, '\Images\', currentImg))
                        newImgName = strcat('tmpimg', string(str2double(imgNum) + 1), '.png');
                        break
                    end
                end
            end

            if ~exist('newImgName', 'var')
                newImgName = 'tmpimg0.png';
            end

            currentTab = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
            if currentTab < 10
                % Add the electrode number to the image
                imageFile = strcat('\Images\', string(currentTab), '.png');
                absPath = fileparts(mfilename('fullpath')); % absolute path to the folder containing the mlapp file
                [numImg, ~, numAlpha] = imread(strcat(absPath, imageFile)); % Load image
            end

            numWidth = size(numAlpha, 1) - 1;
            numHeight = size(numAlpha, 2) - 1;

            tmpLayer = app.imgLayer{currentTab, 2};
            tmpAlpha = app.imgLayer{currentTab, 1};
            tmpLayer(pos(2)+numPos:pos(2)+numWidth+numPos, pos(1)-numHeight/2:pos(1)+numHeight/2, :) = ...
                img(pos(2)+numPos:pos(2)+numWidth+numPos, pos(1)-numHeight/2:pos(1)+numHeight/2, :) + numImg;
            tmpAlpha(pos(2)+numPos:pos(2)+numWidth+numPos, pos(1)-numHeight/2:pos(1)+numHeight/2, 1) = ...
                tmpAlpha(pos(2)+numPos:pos(2)+numWidth+numPos, pos(1)-numHeight/2:pos(1)+numHeight/2, 1) + numAlpha;

            app.imgLayer{currentTab, 2} = tmpLayer;
            app.imgLayer{currentTab, 1} = tmpAlpha;
            %% Add up all the alpha map layers
            for layer = 1:size(app.imgLayer, 1)
                if size(app.imgLayer{layer, 1}, 1) == 0
                    app.imgLayer{layer, 1} = uint8(zeros(size(img)));
                    app.imgLayer{layer, 2} = uint8(zeros(size(img)));
                end
                size(app.imgLayer{layer, 1})
                alpha = alpha + app.imgLayer{layer, 1};
                img = img + app.imgLayer{layer, 2};
                tmpX = app.ringInnerAlpha{layer, 1};
                tmpY = app.ringInnerAlpha{layer, 2};
                for i = 1:size(app.ringInnerAlpha{layer, 1})
                    alpha(tmpX(i), tmpY(i)) = 0;
                end
            end
            % Save the new image to a file
            imwrite(img(1:originalImageSize(1), 1:originalImageSize(2), 1:3), strcat(absPath, '\Images\', newImgName), 'Alpha', alpha(1:originalImageSize(1), 1:originalImageSize(2)));
            app.Image.ImageSource = strcat(absPath, '\Images\', newImgName);

        end

        function returnElectrodePixPos(app, PosToUpdate)
            % If an electrode has been clicked, retrieve the center
            % position of that electrode and send this position and the
            % electrode type to drawElectrodePosition so the electrode can
            % be added to the electrode layout image.

            % Electrode positions
            if strcmp(app.CaptypeDropDown.Value, '1010 (default)')
                if strcmp(PosToUpdate, 'Fp1')
                    pos = [263, 108];
                elseif strcmp(PosToUpdate, 'Fp2')
                    pos = [417, 108];

                elseif strcmp(PosToUpdate, 'F7')
                    pos = [148, 194];
                elseif strcmp(PosToUpdate, 'F8')
                    pos = [533, 194];

                elseif strcmp(PosToUpdate, 'F3')
                    pos = [244, 211];
                elseif strcmp(PosToUpdate, 'Fz')
                    pos = [340, 211];
                elseif strcmp(PosToUpdate, 'F4')
                    pos = [438, 211];

                elseif strcmp(PosToUpdate, 'T3')
                    pos = [101, 333];
                elseif strcmp(PosToUpdate, 'T4')
                    pos = [577, 333];

                elseif strcmp(PosToUpdate, 'C3')
                    pos = [222, 333];
                elseif strcmp(PosToUpdate, 'Cz')
                    pos = [340, 333];
                elseif strcmp(PosToUpdate, 'C4')
                    pos = [460, 333];

                elseif strcmp(PosToUpdate, 'P3')
                    pos = [243 453];
                elseif strcmp(PosToUpdate, 'Pz')
                    pos = [340 453];
                elseif strcmp(PosToUpdate, 'P4')
                    pos = [438 453];

                elseif strcmp(PosToUpdate, 'T5')
                    pos = [148, 472];
                elseif strcmp(PosToUpdate, 'T6')
                    pos = [532, 472];

                elseif strcmp(PosToUpdate, 'O1')
                    pos = [263, 559];
                elseif strcmp(PosToUpdate, 'O2')
                    pos = [417, 559];
                end
            end
            currentTab = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
            ElecType = app.ElecTypeDropDown{currentTab}.Value;
            if strcmp(ElecType, 'Disc (default)')
                ElecSize = app.RadiusValue{currentTab}.Value;
            elseif strcmp(ElecType, 'Pad')
                ElecSize = [app.WidthValue{currentTab}.Value, app.LengthValue{currentTab}.Value];
            elseif strcmp(ElecType, 'Ring')
                ElecSize = [app.InnerRadiusValue{currentTab}.Value, app.OuterRadiusValue{currentTab}.Value];
            end
            drawElectrodePosition(app, pos, ElecType, ElecSize)
        end

        function ringRadCallback(app, ~)
            % If a user enters a value for ring radius that is physically
            % impossible (inner radius larger than outer radius, or
            % radius of 0 or less) reset the radius to the default values.
            currentTab = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
            if app.InnerRadiusValue{currentTab}.Value > app.OuterRadiusValue{currentTab}.Value
                uif = uifigure();
                uialert(uif,'Inner electrode radius cannot be larger than outer electrode radius. Ring radii have been reset to the default values.', 'Warning','Icon','warning');
                app.InnerRadiusValue{currentTab}.Value = 4;
                app.OuterRadiusValue{currentTab}.Value = 6;
            elseif app.InnerRadiusValue{currentTab}.Value <= 0
                uif = uifigure();
                uialert(uif,'Inner electrode radius must be a positive number. Inner radius has been reset to the default value.', 'Warning','Icon','warning');
                app.InnerRadiusValue{currentTab}.Value = 4;
            elseif app.OuterRadiusValue{currentTab}.Value <= 0
                uif = uifigure();
                uialert(uif,'Outer electrode radius must be a positive number. Outer radius has been reset to the default value.', 'Warning','Icon','warning');
                app.OuterRadiusValue{currentTab}.Value = 6;
            end
        end

        function elecHeightCallback(app, ~)
            % Prompt the user with a UI alert if they enter an electrode
            % height that is 0 or less and reset the electrode height to
            % 2mm.
            currentTab = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
            if app.HeightValue{currentTab}.Value <= 0
                uif = uifigure();
                uialert(uif,'Electrode height must be a positive number. Resetting electrode height to 2mm.', 'Warning','Icon','warning');
                app.HeightValue{currentTab}.Value = 2;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create Image
            app.Image = uiimage(app.ElectrodepositionTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Position = [0 5 app.TabGroup3.Position(3) app.TabGroup3.Position(4)-40];
            app.Image.ScaleMethod = 'fit';
            imageFile = '\Images\1020EEG.png';
            absPath = fileparts(mfilename('fullpath')); % absolute path to the folder containing the mlapp file
            imagePath = fullfile(absPath, imageFile); % absolute path to the image file
            app.Image.ImageSource = imagePath;
            app.UIFigure.Name = 'ROAST: A simulator for transcranial electrical stimulation';
            app.SimulationtagEditField.Value = "Date and time e.g. 20221225T120100";

            %createElecTab(app, 1);
            fillElecTab(app, 1);
            createElecTab(app, 2);
            fillElecTab(app, 2);

            app.PreviousElecType{1} = 'Disc (default)';
            app.PreviousElecType{2} = 'Disc (default)';

            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @resizeAppComponents, true);

            app.ringInnerAlpha = cell(1, 2);

            % Create SaveimageButton
            app.SaveimageButton = uibutton(app.ElectrodepositionTab, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Position = [497 13 100 22];
            app.SaveimageButton.Text = 'Save image';

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [T1file,T1path] = uigetfile('*.nii');  % Open a T1 MRI file
            app.T1FileLoc.Value = strcat(T1path,T1file);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [T2file,T2path] = uigetfile('*.nii');  % Open a T2 MRI file
            app.T2FileLoc.Value = strcat(T2path,T2file);
        end

        % Value changed function: NumberofelectrodesEditField
        function NumberofelectrodesEditFieldValueChanged(app, event)
            RequiredNumTabs = app.NumberofelectrodesEditField.Value;
            CurrentNumTabs = size(app.DynamicElecTab,2) + 1;

            if (mod(RequiredNumTabs, 1) ~= 0) && (RequiredNumTabs < 2)
                uif = uifigure();
                uialert(uif,'Please enter a whole number value of 2 or more in "Number of electrodes"','Warning','Icon','warning');
            elseif (RequiredNumTabs < 2)
                uif = uifigure();
                uialert(uif,'Please enter a value of 2 or more in "Number of electrodes"','Warning','Icon','warning');
            elseif mod(RequiredNumTabs, 1) ~= 0
                uif = uifigure();
                uialert(uif,'Please enter a whole number in "Number of electrodes"','Warning','Icon','warning');
            elseif RequiredNumTabs > CurrentNumTabs % Tab creation
                NumNewTabs = RequiredNumTabs - CurrentNumTabs;
                for i = 1:NumNewTabs
                    createElecTab(app, (CurrentNumTabs + i));
                    fillElecTab(app, (CurrentNumTabs + i));
                end
            elseif RequiredNumTabs < CurrentNumTabs % Tab deletion
                NumDeleteTabs = CurrentNumTabs - RequiredNumTabs;
                for i = 1:NumDeleteTabs
                    deleteElecTab(app, (CurrentNumTabs - i));
                end
                %fillElecTab(app, RequiredNumTabs+1);
            end
        end

        % Callback function
        function TypeDropDownValueChanged(app, event)
            TabNum = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
            if TabNum == 1
                currentTabObj = app.ElecTab;
            else
                currentTabObj = app.DynamicElecTab{TabNum-1};
            end

            % Create pad orientation dropdown for the first tab
            value = app.ElecTypeDropDown{TabNum}.Value;
            if ~strcmp(value, app.PreviousElecType{TabNum}) % Type has changed
                if strcmp(app.PreviousElecType{TabNum}, 'Pad')
                    % No longer pad so length, width and orientaiton
                    % dropdown deleted
                    delete(app.OrientationDropDown);
                    delete(app.OrientationText);

                    delete(app.LengthLabel);
                    delete(app.LengthValue{TabNum});

                    delete(app.WidthLabel);
                    delete(app.WidthValue{TabNum});

                elseif strcmp(app.PreviousElecType{TabNum}, 'Disc (default)')
                    % No longer disc so radius deleted
                    delete(app.RadiusValue{TabNum});
                    delete(app.RadiusLabel{TabNum});
                elseif strcmp(app.PreviousElecType{TabNum}, 'Ring')
                    delete(app.InnerRadiusLabel);
                    delete(app.InnerRadiusValue{TabNum});
                    delete(app.OuterRadiusLabel);
                    delete(app.OuterRadiusValue{TabNum});
                end
            end
            app.PreviousElecType{TabNum} = value;
            % Create dropdowm menu for electrode orientation and
            % include x and y sizing
            if strcmp(value, 'Pad')
                app.OrientationDropDown = uidropdown(currentTabObj);
                app.OrientationDropDown.Items = {'Long axis going left/right', 'Long axis going front/back', 'Long axis going up/down'};
                app.OrientationDropDown.Position = [95 10 175 22];
                app.OrientationDropDown.Value = 'Long axis going left/right';

                app.OrientationText = uilabel(currentTabObj);
                app.OrientationText.HorizontalAlignment = 'left';
                app.OrientationText.Position = [6 10 90 22];
                app.OrientationText.Text = 'Orientation';

                app.HeightValue{TabNum}.Value = 3;
                app.HeightValue{TabNum}.ValueChangedFcn = createCallbackFcn(app, @elecHeightCallback, true);

                app.LengthLabel = uilabel(currentTabObj);
                app.LengthLabel.HorizontalAlignment = 'left';
                app.LengthLabel.Position = [6 45 74 22];
                app.LengthLabel.Text = 'Length (mm)';

                app.LengthValue{TabNum}  = uieditfield(currentTabObj, 'numeric');
                app.LengthValue{TabNum}.Position = [150 45 34 22];
                app.LengthValue{TabNum}.Value = 50;

                app.WidthLabel = uilabel(currentTabObj);
                app.WidthLabel.HorizontalAlignment = 'left';
                app.WidthLabel.Position = [210 45 71 22];
                app.WidthLabel.Text = 'Width (mm)';

                app.WidthValue{TabNum} = uieditfield(currentTabObj, 'numeric');
                app.WidthValue{TabNum} .Position = [290 45 34 22];
                app.WidthValue{TabNum}.Value = 30;

            elseif strcmp(value, 'Disc (default)')
                app.HeightValue{TabNum}.ValueChangedFcn = createCallbackFcn(app, @elecHeightCallback, true);

                % Create RadiusLabel{TabNum}
                app.RadiusLabel{TabNum} = uilabel(currentTabObj);
                app.RadiusLabel{TabNum}.Position = [6 45 70 22];
                app.RadiusLabel{TabNum}.Text = 'Radius (mm)';

                % Create RadiusValue{TabNum}
                app.RadiusValue{TabNum} = uieditfield(currentTabObj, 'numeric');
                app.RadiusValue{TabNum}.Position = [150 45 37 22];
                app.RadiusValue{TabNum}.Value = 6;

            elseif strcmp(value, 'Ring')
                app.InnerRadiusLabel = uilabel(currentTabObj);
                app.InnerRadiusLabel.HorizontalAlignment = 'left';
                app.InnerRadiusLabel.Position = [6 10 100 22];
                app.InnerRadiusLabel.Text = 'Inner radius (mm)';

                app.InnerRadiusValue{TabNum} = uieditfield(currentTabObj, 'numeric');
                app.InnerRadiusValue{TabNum}.Position = [117 10 34 22];
                app.InnerRadiusValue{TabNum}.Value = 4;
                app.InnerRadiusValue{TabNum}.ValueChangedFcn = createCallbackFcn(app, @ringRadCallback, true);

                app.OuterRadiusLabel = uilabel(currentTabObj);
                app.OuterRadiusLabel.HorizontalAlignment = 'left';
                app.OuterRadiusLabel.Position = [6 45 100 22];
                app.OuterRadiusLabel.Text = 'Outer radius (mm)';

                app.OuterRadiusValue{TabNum} = uieditfield(currentTabObj, 'numeric');
                app.OuterRadiusValue{TabNum}.Position = [117 45 34 22];
                app.OuterRadiusValue{TabNum}.Value = 6;
                app.OuterRadiusValue{TabNum}.ValueChangedFcn = createCallbackFcn(app, @ringRadCallback, true);
                
                app.HeightValue{TabNum}.ValueChangedFcn = createCallbackFcn(app, @elecHeightCallback, true);
            end
        end

        % Callback function
        function PositionEditFieldValueChanged(app, event)
            value = app.PositionEditField.Value;
            ElectrodePos = ["nz" "lpa" "rpa" "Fp1", "Fpz", "Fp2", "F7", "F3", "Fz", "F4", "F8", ...
                "T7", "C3", "Cz", "C4", "T8",  "P7", "P3", "Pz", "P4", "P8", "O1", "Oz", "O2"];
            if strcmp(app.CaptypeDropDown.Value, '1020')
                if ~ismember(value, ElectrodePos)
                    uif = uifigure();
                    uialert(uif,'The electrode position you entered was not valid. Please refer to the electrode position image.','Warning','Icon','warning');
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Display settings chosen by user to the user
            % When approved, run ROAST

            % Check T1 MRI is included, if not alert user and do not
            % continue
            T1Location = app.T1FileLoc.Value;
            if isempty(T1Location)
                uif = uifigure();
                uialert(uif,'Please select a T1 MRI file before running ROAST.','Warning','Icon','warning');
                return;
            end

            T2Location = app.T2FileLoc.Value;
            
            numElectrodes =  size(app.DynamicElecTab,2) + 1;
            % Alert user if sum of stimulation magntiudes is not equal
            % to zero
            sumElecMag = 0; absSumElecMag = 0;
            for i = 1:numElectrodes
                sumElecMag = sumElecMag + app.ElecMagValue{i}.Value;
                absSumElecMag = absSumElecMag + abs(app.ElecMagValue{i}.Value);
            end
            if sumElecMag ~= 0
                uif = uifigure();
                uialert(uif,'Please ensure the sum of electrode intensities are equal to 0.','Warning','Icon','warning');
                return;
            elseif absSumElecMag == 0
                uif = uifigure();
                uialert(uif,'Please set electrode stimulation intensities.','Warning','Icon','warning');
                return; 
            end

            electrodeInfo = cell(numElectrodes*2, 1);
            for i=1:numElectrodes
                if i == 1
                    electrodeInfo{1} = char(app.ElecPosValue{i}.Value);
                    electrodeInfo{2} = double(app.ElecMagValue{i}.Value);
                else
                    electrodeInfo{(i*2)-1} = char(app.ElecPosValue{i}.Value);
                    electrodeInfo{i*2} = double(app.ElecMagValue{i}.Value);   
                end
            end
            %% Check if any advanced settings have been changed
            advancedRoastInput = [];
            if app.ResamplingCheckBox.Value
                advancedRoastInput = [advancedRoastInput, 'resampling', 'on'];
            end

            if app.ZeropaddingEditField.Value ~= 0
                advancedRoastInput = [advancedRoastInput, 'zeropadding', app.ZeropaddingEditField.Value];
            end

            if ~strcmp(app.SimulationtagEditField.Value, "Date and time e.g. 20221225T120100")
                advancedRoastInput = [advancedRoastInput, 'simulationTag', string(pap.SimulationtagEditField.Value)];
            end
            % Check each conductivity value and save to struct if they have
            % been changed from default
            NewConductivies = struct();
            if app.WhitematterEditField.Value ~= 0.126
                %PrintToScreen(app, strcat("Advanced setting: White matter conductivity has been changed to ", string(app.WhitematterEditField.Value), " S/m"));
                NewConductivies.white = app.WhitematterEditField.Value;
            end
            if app.GraymatterEditField.Value ~= 0.276
                %PrintToScreen(app, strcat("Advanced setting: Grey matter conductivity has been changed to ", string(app.GraymatterEditField.Value), " S/m"));
                NewConductivies.gray = app.GraymatterEditField.Value;
            end
            if app.CerebrospinalfluidEditField.Value ~= 1.65
                %PrintToScreen(app, strcat("Advanced setting: Cerebrospinal fluid conductivity has been changed to ", string(app.CerebrospinalfluidEditField.Value), " S/m"));
                NewConductivies.csf = app.CerebrospinalfluidEditField.Value;
            end
            if app.BoneEditField.Value ~= 0.01
                %PrintToScreen(app, strcat("Advanced setting: Bone conductivity has been changed to ", string(app.BoneEditField.Value), " S/m"));
                NewConductivies.bone = app.BoneEditField.Value;
            end
            if app.SkinEditField.Value ~= 0.465
                %PrintToScreen(app, strcat("Advanced setting: Skin conductivity has been changed to ", string(app.SkinEditField.Value), " S/m"));
                NewConductivies.skin = app.SkinEditField.Value;
            end
            if app.AirEditField.Value ~= 2.5e-14
                %PrintToScreen(app, strcat("Advanced setting: Air conductivity has been changed to ", string(app.AirEditField.Value), " S/m"));
                NewConductivies.air = app.AirEditField.Value;
            end
            if app.GelEditField.Value ~= 0.3
                %PrintToScreen(app, strcat("Advanced setting: Gel conductivity has been changed to ", string(app.GelEditField.Value), " S/m"));
                NewConductivies.gel = app.GelEditField.Value;
            end
            if app.ElectrodeEditField.Value ~= 5.9e7
                %PrintToScreen(app, strcat("Advanced setting: Electrode conductivity has been changed to ", string(app.ElectrodeEditField.Value), " S/m"));
                NewConductivies.electrode = app.ElectrodeEditField.Value;
            end

            % Check each mesh option and save to struct if they have
            % been changed from default
            NewMeshOptions = struct();
            if app.MaximalsurfaceelementsizeEditField.Value ~= 5
                %PrintToScreen(app, strcat("Advanced setting: Maximal surface element size has been changed to ", string(app.MaximalsurfaceelementsizeEditField.Value), " S/m"));
                NewMeshOptions.radbound = app.MaximalsurfaceelementsizeEditField.Value;
            end
            if app.MinimalangleofasurfacetriangleEditField.Value ~= 30
                %PrintToScreen(app, strcat("Advanced setting: Minimum angle of a surface triangle has been changed to ", string(app.MinimalangleofasurfacetriangleEditField.Value), "S /m"));
                NewMeshOptions.angbound = app.MinimalangleofasurfacetriangleEditField.Value;
            end
            if app.MaximumdistancebetweenEditField.Value ~= 0.3
                %PrintToScreen(app, strcat("Advanced setting: Maximal distance surface bounding circle and element bounding sphere centers has been changed to ", string(app.MaximumdistancebetweenEditField.Value), " S/m"));
                NewMeshOptions.distbound = app.MaximumdistancebetweenEditField.Value;
            end
            if app.MaximalradiusedgeratioEditField.Value ~= 3
                %PrintToScreen(app, strcat("Advanced setting: Maximal radius-edge ratio has been changed to ", string(app.MaximalradiusedgeratioEditField.Value), " S/m"));
                NewMeshOptions.reratio = app.MaximalradiusedgeratioEditField.Value;
            end
            if app.MaximaltetrahedralelementvolumeEditField.Value ~= 10
                %PrintToScreen(app, strcat("Advanced setting: Maximal tetrahedral element volume has been changed to ", string(app.MaximaltetrahedralelementvolumeEditField.Value), " S/m"));
                NewMeshOptions.maxvol = app.MaximaltetrahedralelementvolumeEditField.Value;
            end

            
            if isempty(T2Location)
                fprintf("--- PREROAST APP --- \n")
                class(app)
                roast(T1Location, electrodeInfo, 'capType', '1020', 'app', app)
                
            else
                roast(T1Location, electrodeInfo, 'T2', T2Location, 'app', app)
            end
        end

        % Callback function
        function ImageClicked(app, event)
            % Electrode positioning image has been clicked. Discern which
            % electrode (if any) was intended to be selected, update this
            % in the UI and update returnElectrodePixPos with the position
            % of the electrode to be added in the UI.

            % As the width of the window changes the position of the click
            % changes. This calculates the difference so regardless of
            % window size the click position always aligns with electrode
            % in image
            ClickPos = app.UIFigure.CurrentPoint; % Position of click
            PosToUpdate = 'NoUpdate'; % Default to not updating electrode position

            %% Track click location and save new electrode position in  PosToUpdate
            tabRatio = app.TabGroup3.Position(3) / app.TabGroup3.Position(4);

            if tabRatio > app.IMG_RATIO
                % Spaces to the side of the image
                xShift = round((app.TabGroup3.Position(3)-(app.TabGroup3.Position(4)*(1/app.IMG_RATIO)))/2);
                YClickPerc = ClickPos(2) / ((app.TabGroup3.Position(4)-40) / 100);
                XClickPerc = (ClickPos(1) - xShift - app.LEFT_COLLUMN_WIDTH) / (((app.TabGroup3.Position(4)*(1/app.IMG_RATIO))) / 100);
            else
                % Spaces above and below the image
                yShift = round((app.TabGroup3.Position(4)-(app.TabGroup3.Position(3)*(app.IMG_RATIO)))/2) - 15;
                YClickPerc = (ClickPos(2)-yShift) / ((app.TabGroup3.Position(3)*app.IMG_RATIO)/ 100);
                %YClickPerc = round((app.TabGroup3.Position(4)-(app.TabGroup3.Position(3)*IMG_RATIO))/2);
                XClickPerc = (ClickPos(1)-app.LEFT_COLLUMN_WIDTH) / (app.Image.Position(3) / 100);
            end

            if (YClickPerc > 46) && (YClickPerc < 56) % Middle row Y range
                if(XClickPerc > 9) && (XClickPerc < 19)
                    PosToUpdate = 'T3';
                elseif(XClickPerc > 28) && (XClickPerc < 38)
                    PosToUpdate = 'C3';
                elseif(XClickPerc > 46) && (XClickPerc < 56)
                    PosToUpdate = 'Cz';
                elseif(XClickPerc > 64) && (XClickPerc < 74)
                    PosToUpdate = 'C4';
                elseif(XClickPerc > 83) && (XClickPerc < 93)
                    PosToUpdate = 'T4';
                end
            end
            if (YClickPerc > 65) && (YClickPerc < 75) % F3, Fz, F4
                if (XClickPerc > 31) && (XClickPerc < 41)
                    PosToUpdate = 'F3';
                elseif(XClickPerc > 46) && (XClickPerc < 56)
                    PosToUpdate = 'Fz';
                elseif(XClickPerc > 61) && (XClickPerc < 71)
                    PosToUpdate = 'F4';
                end
            end
            if (YClickPerc > 27) && (YClickPerc < 38) % P3, Pz, P4
                if (XClickPerc > 30) && (XClickPerc < 41)
                    PosToUpdate = 'P3';
                elseif(XClickPerc > 46) && (XClickPerc < 56)
                    PosToUpdate = 'Pz';
                elseif(XClickPerc > 61) && (XClickPerc < 71)
                    PosToUpdate = 'P4';
                end
            end
            if (YClickPerc > 10) && (YClickPerc < 21) % O1, O2
                if (XClickPerc > 34) && (XClickPerc < 45)
                    PosToUpdate = 'O1';
                elseif(XClickPerc > 58) && (XClickPerc < 69)
                    PosToUpdate = 'O2';
                end
            end
            if (YClickPerc > 81) && (YClickPerc < 92) % Fp1, Fp2
                if (XClickPerc > 34) && (XClickPerc < 45)
                    PosToUpdate = 'Fp1';
                elseif(XClickPerc > 58) && (XClickPerc < 69)
                    PosToUpdate = 'Fp2';
                end
            end
            if (YClickPerc > 24) && (YClickPerc < 35) % T5, T6
                if (XClickPerc > 16) && (XClickPerc < 27)
                    PosToUpdate = 'T5';
                elseif(XClickPerc > 75) && (XClickPerc < 86)
                    PosToUpdate = 'T6';
                end
            end
            if (YClickPerc > 68) && (YClickPerc < 77) % F7, F8
                if (XClickPerc > 16) && (XClickPerc < 27)
                    PosToUpdate = 'F7';
                elseif(XClickPerc > 75) && (XClickPerc < 85)
                    PosToUpdate = 'F8';
                end
            end

            %% Update electrode position in relevant electrode tab if needed
            if ~strcmp(PosToUpdate, 'NoUpdate')
                CurrentTab = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
                for i = 1:length(app.ElecPosValue)
                    if strcmp(app.ElecPosValue{i}.Value, PosToUpdate)
                        app.imgLayer{i, 1} = [];
                        app.imgLayer{i, 2} = [];
                        app.ringInnerAlpha{i, 1} = [];
                        app.ringInnerAlpha{i, 2} = [];

                        app.ElecPosValue{i}.Value = '';
                    end
                end
                returnElectrodePixPos(app, PosToUpdate);
                app.ElecPosValue{CurrentTab}.Value = PosToUpdate;

            end

        end

        % Callback function
        function SaveimageButtonPushed(app, event)
            % Allow user to save electrode montage image seperately
            absPath = fileparts(mfilename('fullpath'));
            imgFiles = dir(strcat(absPath, '\Images\'));
            imgRGB = 0;
            for i = 1:length(imgFiles)
                tmpImgName = imgFiles(i).name;
                if length(tmpImgName) > 6
                    if strcmp(tmpImgName(1:6), 'tmpimg')
                        [imgRGB, ~, imgAlpha] = imread(strcat(absPath, '\Images\', tmpImgName));
                        % Turn transparent pixels to white
                        for xPixel = 1:size(imgRGB, 1)
                            for yPixel = 1:size(imgRGB, 2)
                                if sum(imgRGB(xPixel, yPixel, :)) == 0
                                    if sum(imgAlpha(xPixel, yPixel, :)) == 0
                                        imgRGB(xPixel, yPixel, :) = uint8([255, 255, 255]);
                                        imgAlpha(xPixel, yPixel, :) = uint8(255);
                                    end
                                end
                            end
                        end
                    end
                end
            end
            
            if imgRGB == 0
                % Unable to find the temporary image, so no data to save
                uif = uifigure();
                uialert(uif,'An error has occured. Unable to save the image.', 'Warning','Icon','warning');
            else
                defaultFileName = 'montage.png';
                [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
                if baseFileName ~= 0
                    imwrite(imgRGB, strcat(folder, baseFileName), 'Alpha', imgAlpha);
                    return;
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {512, 512};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {368, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.MainmenuTab = uitab(app.TabGroup2);
            app.MainmenuTab.Title = 'Main menu';
            app.CaptypeDropDownLabel = uilabel(app.MainmenuTab);
            app.CaptypeDropDownLabel.Text = 'Cap type';
            app.CaptypeDropDown = uidropdown(app.MainmenuTab);
            app.CaptypeDropDown.Items = {'1010 (default)', '1020', '1005', 'BioSemi', 'EGI'};
            app.CaptypeDropDown.Value = '1010 (default)';
            app.NumberofelectrodesEditFieldLabel = uilabel(app.MainmenuTab);
            app.NumberofelectrodesEditFieldLabel.Text = 'Number of electrodes';
            app.NumberofelectrodesEditField = uieditfield(app.MainmenuTab, 'numeric');
            app.NumberofelectrodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofelectrodesEditFieldValueChanged, true);
            app.NumberofelectrodesEditField.Value = 2;
            app.ElectrodeconfigurationLabel = uilabel(app.MainmenuTab);
            app.ElectrodeconfigurationLabel.Text = 'Electrode configuration';
            app.ElectrodeTabGroup = uitabgroup(app.MainmenuTab);
            app.ElecTab = uitab(app.ElectrodeTabGroup);
            app.ElecTab.Title = '1';
            app.StartButton = uibutton(app.MainmenuTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.MainmenuTab, 'push');
            app.StopButton.Text = 'Stop';
            app.BrowseButton = uibutton(app.MainmenuTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.T1FileLoc = uieditfield(app.MainmenuTab, 'text');
            app.BrowseButton_2 = uibutton(app.MainmenuTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.T2FileLoc = uieditfield(app.MainmenuTab, 'text');
            app.SelectT1MRIfileLabel = uilabel(app.MainmenuTab);
            app.SelectT1MRIfileLabel.Text = 'Select T1 MRI file';
            app.OptionalSelectT2MRIfileLabel = uilabel(app.MainmenuTab);
            app.OptionalSelectT2MRIfileLabel.Text = '(Optional) Select T2 MRI file';
            app.SavesettingsButton = uibutton(app.MainmenuTab, 'push');
            app.SavesettingsButton.Text = 'Save settings';
            app.LoadsettingsButton = uibutton(app.MainmenuTab, 'push');
            app.LoadsettingsButton.Text = 'Load settings';
            app.TargetedtESTab = uitab(app.TabGroup2);
            app.TargetedtESTab.Title = 'Targeted tES';
            app.StartButton_2 = uibutton(app.TargetedtESTab, 'push');
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.TargetedtESTab, 'push');
            app.StopButton_2.Text = 'Stop';
            app.BrowseButton_3 = uibutton(app.TargetedtESTab, 'push');
            app.BrowseButton_3.Text = 'Browse';
            app.T1FileLoc_2 = uieditfield(app.TargetedtESTab, 'text');
            app.BrowseButton_4 = uibutton(app.TargetedtESTab, 'push');
            app.BrowseButton_4.Text = 'Browse';
            app.T2FileLoc_2 = uieditfield(app.TargetedtESTab, 'text');
            app.SelectT1MRIfileLabel_2 = uilabel(app.TargetedtESTab);
            app.SelectT1MRIfileLabel_2.Text = 'Select T1 MRI file';
            app.OptionalSelectT2MRIfileLabel_2 = uilabel(app.TargetedtESTab);
            app.OptionalSelectT2MRIfileLabel_2.Text = '(Optional) Select T2 MRI file';
            app.SavesettingsButton_2 = uibutton(app.TargetedtESTab, 'push');
            app.SavesettingsButton_2.Text = 'Save settings';
            app.LoadsettingsButton_2 = uibutton(app.TargetedtESTab, 'push');
            app.LoadsettingsButton_2.Text = 'Load settings';
            app.CapType1010TypeDiscRadius6mmHeight2mmLabel = uilabel(app.TargetedtESTab);
            app.CapType1010TypeDiscRadius6mmHeight2mmLabel.Text = 'Cap Type (1010)  Type (Disc)  Radius (6mm)  Height (2mm)';
            app.CoordinatetypeDropDownLabel = uilabel(app.TargetedtESTab);
            app.CoordinatetypeDropDownLabel.Text = 'Coordinate type';
            app.CoordinatetypeDropDown = uidropdown(app.TargetedtESTab);
            app.CoordinatetypeDropDown.Items = {'MNI', 'Voxel'};
            app.CoordinatetypeDropDown.Value = 'Voxel';
            app.PriorityDropDownLabel = uilabel(app.TargetedtESTab);
            app.PriorityDropDownLabel.Text = 'Priority';
            app.PriorityDropDown = uidropdown(app.TargetedtESTab);
            app.PriorityDropDown.Items = {'Max Intensity', 'Max Focality'};
            app.PriorityDropDown.Value = 'Max Intensity';
            app.AlgorithmDropDownLabel = uilabel(app.TargetedtESTab);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.TargetedtESTab);
            app.AlgorithmDropDown.Items = {'Max L1', 'Max L1per'};
            app.AlgorithmDropDown.Value = 'Max L1per';
            app.ElectricfieldorientationDropDownLabel = uilabel(app.TargetedtESTab);
            app.ElectricfieldorientationDropDownLabel.Text = 'Electric field orientation';
            app.ElectricfieldorientationDropDown = uidropdown(app.TargetedtESTab);
            app.ElectricfieldorientationDropDown.Items = {'Radial in', 'Radial out', 'Right', 'Left', 'Anterior', 'Posterior', 'Right anterior', 'Right posterior', 'Left anterior', 'Left posterior', 'Optimal'};
            app.ElectricfieldorientationDropDown.Value = 'Right posterior';
            app.AdvancedTab = uitab(app.TabGroup2);
            app.AdvancedTab.Title = 'Advanced';
            app.SimulationtagEditFieldLabel = uilabel(app.AdvancedTab);
            app.SimulationtagEditFieldLabel.Text = 'Simulation tag';
            app.SimulationtagEditField = uieditfield(app.AdvancedTab, 'text');
            app.ResamplingCheckBox = uicheckbox(app.AdvancedTab);
            app.ResamplingCheckBox.Text = 'Resampling';
            app.ZeropaddingEditFieldLabel = uilabel(app.AdvancedTab);
            app.ZeropaddingEditFieldLabel.Text = 'Zero padding';
            app.ZeropaddingEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.TabGroup4 = uitabgroup(app.AdvancedTab);
            app.ConductivityvaluesTab = uitab(app.TabGroup4);
            app.ConductivityvaluesTab.Title = 'Conductivity values';
            app.WhitematterEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.WhitematterEditFieldLabel.Text = 'White matter';
            app.WhitematterEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.WhitematterEditField.Value = 0.126;
            app.GraymatterEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.GraymatterEditFieldLabel.Text = 'Gray matter';
            app.GraymatterEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.GraymatterEditField.Value = 0.276;
            app.CerebrospinalfluidLabel = uilabel(app.ConductivityvaluesTab);
            app.CerebrospinalfluidLabel.Text = 'Cerebrospinal fluid ';
            app.CerebrospinalfluidEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.CerebrospinalfluidEditField.Value = 1.65;
            app.BoneEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.BoneEditFieldLabel.Text = 'Bone';
            app.BoneEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.BoneEditField.Value = 0.01;
            app.SkinEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.SkinEditFieldLabel.Text = 'Skin';
            app.SkinEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.SkinEditField.Value = 0.465;
            app.AirEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.AirEditFieldLabel.Text = 'Air';
            app.AirEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.AirEditField.Value = 2.5e-14;
            app.GelEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.GelEditFieldLabel.Text = 'Gel';
            app.GelEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.GelEditField.Value = 0.3;
            app.ElectrodeEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.ElectrodeEditFieldLabel.Text = 'Electrode';
            app.ElectrodeEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.ElectrodeEditField.Value = 59000000;
            app.SmLabel = uilabel(app.ConductivityvaluesTab);
            app.SmLabel.Text = 'S/m';
            app.SmLabel_2 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_2.Text = 'S/m';
            app.SmLabel_3 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_3.Text = 'S/m';
            app.SmLabel_4 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_4.Text = 'S/m';
            app.SmLabel_5 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_5.Text = 'S/m';
            app.SmLabel_6 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_6.Text = 'S/m';
            app.SmLabel_7 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_7.Text = 'S/m';
            app.SmLabel_8 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_8.Text = 'S/m';
            app.MeshoptionsTab = uitab(app.TabGroup4);
            app.MeshoptionsTab.Title = 'Mesh options';
            app.MaximalsurfaceelementsizeEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximalsurfaceelementsizeEditFieldLabel.Text = 'Maximal surface element size';
            app.MaximalsurfaceelementsizeEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximalsurfaceelementsizeEditField.Value = 5;
            app.MinimalangleofasurfacetriangleEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MinimalangleofasurfacetriangleEditFieldLabel.Text = 'Minimal angle of a surface triangle';
            app.MinimalangleofasurfacetriangleEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MinimalangleofasurfacetriangleEditField.Value = 30;
            app.MaximumdistancebetweenEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximumdistancebetweenEditFieldLabel.Text = {'Maximal distance between the '; 'center of the surface bounding circle '; 'and center of the element bounding '; 'sphere'};
            app.MaximumdistancebetweenEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximumdistancebetweenEditField.Value = 0.3;
            app.MaximalradiusedgeratioEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximalradiusedgeratioEditFieldLabel.Text = 'Maximal radius-edge ratio';
            app.MaximalradiusedgeratioEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximalradiusedgeratioEditField.Value = 3;
            app.MaximaltetrahedralelementvolumeEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximaltetrahedralelementvolumeEditFieldLabel.Text = 'Maximal tetrahedral element volume';
            app.MaximaltetrahedralelementvolumeEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximaltetrahedralelementvolumeEditField.Value = 10;
            app.InfoTab = uitab(app.TabGroup2);
            app.InfoTab.Title = 'Info';
            app.Hyperlink = uihyperlink(app.InfoTab);
            app.Hyperlink.URL = 'https://groups.google.com/g/roast-users';
            app.Hyperlink.Text = 'Roast users Google group';
            app.Hyperlink2 = uihyperlink(app.InfoTab);
            app.Hyperlink2.URL = 'https://github.com/andypotatohy/roast';
            app.Hyperlink2.Text = 'Roast github repository with examples';
            app.Hyperlink3 = uihyperlink(app.InfoTab);
            app.Hyperlink3.Text = 'GUI Roast video tutorial';
            app.Hyperlink3_2 = uihyperlink(app.InfoTab);
            app.Hyperlink3_2.Text = 'GUI Roast github repository';
            app.HelpfullinksLabel_2 = uilabel(app.InfoTab);
            app.HelpfullinksLabel_2.Text = 'Helpful links';
            app.Label = uilabel(app.InfoTab);
            app.Label.Text = {'If you use ROAST in your research, please cite the following:'; 'Huang, Y., Datta, A., Bikson, M., Parra, L.C., Realistic '; 'vOlumetric-Approach to Simulate Transcranial Electric Stimulation -- ROAST -- a '; 'fully automated open-source pipeline, Journal of Neural Engineering, Vol. 16, '; 'No. 5, 2019 (prefered reference)'; ''; 'Huang, Y., Datta, A., Bikson, M., Parra, L.C., ROAST: an open-source, fully-automated, '; 'Realistic vOlumetric-Approach-based Simulator for TES, Proceedings of the 40th '; 'Annual International Conference of the IEEE Engineering in Medicine and Biology '; 'Society, Honolulu, HI, July 2018'; ''; 'If you use New York head to run simulation, please also cite the following:'; 'Huang, Y., Parra, L.C., Haufe, S.,2016. The New York Head - A precise standardized '; 'volume conductor model for EEG source localization and tES targeting. '; 'NeuroImage,140, 150-162'; ''; 'If you also use the targeting feature (roast_target), please cite the following:'; 'Dmochowski, J.P., Datta, A., Bikson, M., Su, Y., Parra, L.C., Optimized multi-electrode '; 'stimulation increases focality and intensity at target, Journal of Neural Engineering '; '8 (4), 046011, 2011'; ''; 'Dmochowski, J.P., Datta, A., Huang, Y., Richardson, J.D., Bikson, M., Fridriksson, '; 'J., Parra, L.C., Targeted transcranial direct current stimulation for rehabilitation after '; 'stroke, NeuroImage, 75, 12-19, 2013'; ''; 'Huang, Y., Thomas, C., Datta, A., Parra, L.C., Optimized tDCS for Targeting Multiple '; 'Brain Regions: An Integrated Implementation.Proceedings of the 40th Annual '; 'International Conference of the IEEE Engineering in Medicine and Biology Society, '; 'Honolulu, HI, July 2018, 3545-3548'; ''; 'ROAST was supported by NIH through grants R01MH111896, R01MH111439, '; 'R01NS095123, R44NS092144, R41NS076123, and by Soterix Medical Inc'};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.ElectrodepositionTab = uitab(app.TabGroup3);
            app.ElectrodepositionTab.Title = 'Electrode position';
            app.ClicktoselectLabel = uilabel(app.ElectrodepositionTab);
            app.ClicktoselectLabel.Text = 'Click to select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoastGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end